classdef fftAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FsValueSpinner                 matlab.ui.control.Spinner
        FsValueSpinnerLabel            matlab.ui.control.Label
        MovementLabel                  matlab.ui.control.Label
        totalMovementCount             matlab.ui.control.Label
        currentMovementCountIndex      matlab.ui.control.Label
        movementCounterOfLabel         matlab.ui.control.Label
        SectionNameDropDown            matlab.ui.control.DropDown
        SectionNameDropDownLabel       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        PreviousFileButton             matlab.ui.control.Button
        NextFileButton                 matlab.ui.control.Button
        DataSelectorButtonGroup        matlab.ui.container.ButtonGroup
        DataChannelLabelsDropDown      matlab.ui.control.DropDown
        ClassificationLabelsDropDown_2Label  matlab.ui.control.Label
        ClassificationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationLabelsDropDown   matlab.ui.control.DropDown
        ClassificationLabelsDropDownLabel  matlab.ui.control.Label
        ClassifyButton                 matlab.ui.control.Button
        MetricSelectorButtonGroup      matlab.ui.container.ButtonGroup
        SelectResponseAmplitudeButton  matlab.ui.control.Button
        SelectPeakVelocityButton       matlab.ui.control.Button
        SelectLatencyButton            matlab.ui.control.Button
        EyeSelectorButtonGroup         matlab.ui.container.ButtonGroup
        RightEyeButton                 matlab.ui.control.ToggleButton
        BinocularButton                matlab.ui.control.ToggleButton
        LeftEyeButton                  matlab.ui.control.ToggleButton
        MetricTable                    matlab.ui.control.Table
        nextMovementButton             matlab.ui.control.Button
        previousMovementButton         matlab.ui.control.Button
        LoadDataButton                 matlab.ui.control.Button
        totalFileCountLabel            matlab.ui.control.Label
        currentFileIndexLabel          matlab.ui.control.Label
        ofLabel                        matlab.ui.control.Label
        CurrentFileSelectionLabel      matlab.ui.control.Label
        PupilMovementPlot              matlab.ui.control.UIAxes
        PupilSectionPlot               matlab.ui.control.UIAxes
        HorizontalMovementPlot         matlab.ui.control.UIAxes
        HorizontalSectionPlot          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        currentEyeSelectionIndex = 3; % Default Eye Selection Index (Binocular) 
        currentFileIndex = 1; % Default Current File Index
        currentFileSectionNames % Table Variable Names Properties
        currentLoadedData % Current Sub-Table Loaded from Main Table
        currentMetrics % Child of TableOfMetrics singular table entry 
        currentTableOfMetrics % Loaded Table for all Metric Analysis of File
        currentMovementIndex = 1; % Default Current Movement Index
        currentSectionIndex = 1; % Current Position within Table Data
        currentTableData % Presently Loaded Table Data
        dataLoadedBoolean = false; % Default Load Boolean
        mapSectionNameSectionCount; % Map/Value of Section Name to Counts
        mapDataChannelClassification; % Map/Value of Channel to Classification
        nextDataFileButtonPressed = false; % Default Selection Boolean 
        nextMovementButtonPressed = false; % Default Selection Boolean
        previousDataFileButtonPressed = false; % Default Selection Boolean
        previousMovementButtonPressed = false; % Default Selection Boolean
        totalSelectedFiles % Default Total Selected File Objects
        totalCurrentMovements % Maximum Movements for Section Observed
        userSelectedFilePath % User Selected Filepath for Analysis
        userSelectedFile % User Selected Filename(s) for Analysis Cell Array

        ensembleBinocularPlot = {}; % Empty Cell for Ensemble Binocular Plotting
        ensembleLeftPlot = {}; % Empty Cell for Ensemble Left Plotting
        ensembleRightPlot = {}; % Empty Cell for Ensemble Right Plotting
        %
        eyeSelectionMap % Map between button groups and row index
        maxSamples
        horizontalExtractedMovementDataTable %keeping horizontal data present for section & individual data
        pupilExtractedMovementDataTable % keeping pupil data present for section & individual data
    end

    methods (Access = private)
        % Plotting Movement Data (Ensemble, Average, etc.)
        function plotData(app)
            % Delete existing plots
            delete(app.ensembleBinocularPlot)
            delete(app.ensembleLeftPlot)
            delete(app.ensembleRightPlot)

            app.ensembleBinocularPlot = {}; 
            app.ensembleLeftPlot = {}; 
            app.ensembleRightPlot = {}; 

            [binocularMovementData,leftMovementData,rightMovementData] = app.extractMovementData;

            plotBinocularData = binocularMovementData;
            plotLeftData = leftMovementData;
            plotRightData = rightMovementData;
            alphaValue = .1;
            lineWidthValue = .5;

            % Ensure Tick Axes are Auto
            xticks(app.EyeMovementFigure,'auto');
            yticks(app.EyeMovementFigure,'auto');

            
            switch app.currentEyeSelectionIndex
                case 1
                    % Plot Binocular
                    app.ensembleBinocularPlots = plot(app.HorizontalSectionPlot,plotBinocularData .* binocularGain,'Color', [0, 0, 1, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
                case 2
                    % Plot Left
                    app.ensembleLeftPlots = plot(app.HorizontalSectionPlot,plotLeftData .* leftEyeGain,'Color', [0, 1, 0, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
                case 3
                    % Plot Right
                    app.ensembleRightPlots = plot(app.HorizontalSectionPlot,plotRightData .* rightEyeGain,'Color', [1, 0, 0, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
            end
        end

        % Extract movement data from table data
        function [horizontalExtractedMovementData, pupilExtractedMovementData] = extractMovementData(app)
            
            % Set default values
            extractedMovementData = [];

            % Extract Non-empty cells (cells with movement data)
            selectedMovementData = {app.currentTableData.(app.SectionNameDropDown.Value){~cellfun(@isempty,app.currentTableData.(app.SectionNameDropDown.Value))}}'; % this is the column of data, does not go into cells
            
            % Pull out streams
            %edit 6/3/2024, made app.horizontalExtractedMovementData
            horizontalExtractedMovementData = app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ", "Horizontal"));
            %app.horizontalExtractedMovementDataTable = horizontalExtractedMovementData;
            %above line might not be needed
            pupilExtractedMovementData = app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ", "Pupil"));
            %app.pupilExtractedMovementDataTable = pupilExtractedMovementData;
            %above line might not be needed
        end
       

        function extractedTableData = tableDataExtractor(app, cellTableArray, extractionHeader)
            % Determine Largest Sample Length for Plotting

            maxSamplesList = cellfun(@max,cellfun(@size,cellTableArray,'UniformOutput',false)); % cell table array is % this is the column of data, does not go into cells
            app.maxSamples = max(maxSamplesList);
           

            % Preallocate Space 
            % Number of Rows based on First Cell Array Value
            % Number of Cols based on Size of Cell Array
            extractedTableData = nan(app.maxSamples,size(cellTableArray,1));
            for cellIndex = 1:size(cellTableArray,1) %goes to each cell within the array, now find a way to plot individual FFT
                % Determine if Classification of Specified Data
                % Channel is Good
                if((app.currentTableOfMetrics{cellIndex, app.SectionNameDropDown.Value}{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(app.currentEyeSelectionIndex)) ~= 0) % added in .importedData 6/4/2024, might not be necessary and can be contributing to errors - check and confirm % 6/5/2024 removed .importedData
                    extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = cellTableArray{cellIndex}.(extractionHeader);
                end
            end       
        end

        function FFTValues = createFFTData(app, extractedData)
            fouriervalues = fft(extractedData);
            tempfourier = abs(fouriervalues/app.maxSamples); % the fft function includes a scaling factor of the L (number of samples) between original and transformed signals, tempfourier involves rescaling the values by dividing by the number of samples present % abs taken due to fft providing complex values (want only magnitude, not phase) % tempfourier is the two-sided amplitude spectrum
            FFTValues = tempfourier(1:(app.maxSamples/2)+1); % ensures only positive frequencies will be plotted
            FFTValues(2:end-1) = 2* FFTValues(2:end-1); % spectrum in positive frequencies multiplied by 2, first and end values are zero and nyquist frequencies (do not have complex conjugate pair in negative frequency). We multiply by two so that original signal energy is shown (positive frequencies showing half the signal energy and negative frequncies showing the other half)
        end

        % Create table of NaN tables
        function metricTable = createMetricTable(app)
            % Returns # of tables in each variable of tableData
            sectionInstances = values(app.mapSectionNameSectionCount);
            
            % Creating empty table with same dimensions as tableData
            metricTable = array2table(cell(max([sectionInstances{:}]), size(sectionInstances, 2)), "VariableNames",keys(app.mapSectionNameSectionCount));

            % Iterates across sectionInstances (Columns)
            for sectionIndex = 1:size(sectionInstances, 2)

                % Iterates down number of movements within tableData
                for movementIndex = 1:sectionInstances{sectionIndex} %difference with stepAnalysis module, stepAnalysis has second line of colSize = size(app.MetricTable.ColumnName,1);

                    % Assign metricTable empty NaN for processing
                    metricTable{movementIndex, sectionIndex} = {array2table(NaN(3,size(app.MetricTable.ColumnName, 1)), "VariableNames", app.MetricTable.ColumnName')}; %difference with stepAnalysis module, = {horzcat(array2table(NaN(3,colSize-1),'VariableNames',app.MetricTable.ColumnName(1:end-1),'RowNames',app.MetricTable.RowName),array2table(string(NaN(3,1)),'VariableNames',app.MetricTable.ColumnName(end),'RowNames',app.MetricTable.RowName))};

                end
            end
        end

        % Creates directory for metric table saving
        function metricTablePath = createMetricTableDirectory(~, filepath)
          % Replace Key Processing Words with "ANALYSIS_METRICS"
            metricTablePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_METRICS");
          %check if directory does not exist as - seen within
          %stepAnalysisModule
        end

        % Create Map Container to Map Keys (Section Names) to Values
        % (Movement Counts per Section Name)
        function createSectionNameMap(app)
            % Iterate over each cell in Variable Name
            sectionLogicalCells = cellfun(@(tableVariableName) cellfun(@(sectionData) ~isempty(sectionData),app.currentTableData.(tableVariableName),'UniformOutput',false),app.currentFileSectionNames,'UniformOutput',false);
            sectionMovementCounts = cellfun(@(logicalCell) sum(cell2mat(logicalCell),1), sectionLogicalCells); %will change determining counts to size of function, logical cells might not be necessary
            % Map Keys - Section Names to Values - Section Movement Counts 
            app.mapSectionNameSectionCount = containers.Map(app.currentFileSectionNames,sectionMovementCounts);
        end

        % Create NaN table of tables for metric analysis
        function findMetricTable(app,filePath)
            % Retrieve FileName
            fileName = app.returnFileName;
    
            % Check if table exists
            if(isfile(fullfile(app.createMetricTableDirectory(filePath),fileName)))
                loadedMetricTableObject = load(fullfile(app.createMetricTableDirectory(filePath),fileName)); %changed so app.currentTableOfMetrics already finds importedData 6/10/2024
                app.currentTableOfMetrics = loadedMetricTableObject.importedData;
                app.loadMetricTableData % added 6/12/2024
            % Else create NaN metric table
            else
                app.currentTableOfMetrics = app.createMetricTable;
            end
        end
        
        % Load Next Movement Data within Current Table Data
        function getNextMovement(app)
            % Evaluate Button Presses
            % Expected Next Button Pressed
            if(app.nextMovementButtonPressed)
                % Check Boundaries and Roll Over to 1 if reached
                if(app.currentMovementIndex + 1 <= app.totalCurrentMovements)
                    app.currentMovementIndex = app.currentMovementIndex + 1;
                else
                    app.currentMovementIndex = 1;
                end    

            % Else Previous Button Pressed
            else
                % Check Boundaries and Roll Over to Last File if reached
                if(app.currentMovementIndex - 1 >= 1)
                    app.currentMovementIndex = app.currentMovementIndex - 1;
                else
                    app.currentMovementIndex = app.totalCurrentMovements;
                end
            end

            % Load Subsection of Table Data
            app.loadSectionOfData;

            % Update Metric Table for New Movement Index
            app.loadMetricTableData;

            % Update Labels
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
        end

        function getSectionNames(app)
            % Load Current Table Variable Names
            app.currentFileSectionNames = app.currentTableData.Properties.VariableNames';     
            % Update Dropdown Items
            app.SectionNameDropDown.Items = app.currentFileSectionNames;
            % Set Default Value to First Item
            app.SectionNameDropDown.Value = app.currentFileSectionNames{1};
            % Map Section Names and Movement Counts
            app.createSectionNameMap;
        end

        % Table Data FILE Pathing Retrieval
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataFileButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                % Check Previous Button Press
                elseif(app.previousDataFileButtonPressed)
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end
                % Section Name Change 
                else
                    app.currentFileIndex = 1;
                end
                
                % Update Labels
                app.currentFileIndexLabel.Text = string(app.currentFileIndex);

            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData(); %ensure that a figure is loaded in instead of file % uigetfile('..\REVAMP\revampDATA\t.fig','MultiSelect','on'); % works within loadData code %have to change naming schema

                % Proceed If Load Successful
                if(~isempty(app.userSelectedFile))
                    % Populate Default Count Variable
                    app.currentFileIndex = 1;
                    app.currentMovementIndex = 1;

                    % Evaluate for Single or Multi-File Selection
                    if(ischar(app.userSelectedFile))
                        app.totalSelectedFiles = 1;
                    else
                        app.totalSelectedFiles = size(app.userSelectedFile,1);
                    end

                    % Set Labels
                    app.currentFileIndexLabel.Text = string(app.currentFileIndex);
                    app.totalFileCountLabel.Text = string(app.totalSelectedFiles); 

                    % Load Table Data
                    app.loadTableData;

                    % Update Load Data Boolean
                    app.dataLoadedBoolean = true;
                end
            end
        end
        
        function loadTableData(app)
            % Load Current Table Path and Filename
            if(ischar(app.userSelectedFile))
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile;

            else
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile{app.currentFileIndex});
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile{app.currentFileIndex};
            end
            % Load Map of Section Names and Expected Counts
            app.getSectionNames;
            % Get Metric Table (New if Does Not Exist or Returns File if
            % Exists)
            app.findMetricTable(app.userSelectedFilePath);
            % Load in Metric Table File
            app.loadMetricTableData; 
            % Load in Section Data of File (Default)
            app.loadSectionOfData;

        end

        % Load Metric Table Based on Current Movement Index (Row) and
        % Section Index (Column)
        function loadMetricTableData(app)
           app.currentMetrics = app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex}; % 6/5/2024 removed .importedData 
           app.MetricTable.Data = app.currentMetrics{:};
        end

        function loadSectionOfData(app)
            app.currentLoadedData = app.currentTableData{app.currentMovementIndex,app.currentSectionIndex}; 
            app.updateSectionPlot;
            app.updateMovementPlot; % added 6/3/2024
        end

       % Determination of frequency & plotting of FFT data to create a
       % power frequency plot
        function updateSectionPlot(app)

            % Pull and Plot Section Data -> commented lines present are for concating data
            [horizontaldata, pupildata] = app.extractMovementData;
            frequency = app.FsValueSpinner.Value/app.maxSamples.*(0:(app.maxSamples/2)); %Calculation results in frequency based on sampling rate, provides values from 0 to the Nyquist frequency (Fs/2)
            %Pre-allocate data
            horizontalSectionData = (1:size(horizontaldata,1))'; 
            horizontalSectionData = 0.*(horizontalSectionData); % error was occuring when attempting to use zeros function -> requested array exceeds the maximum possible variable size (multiplied by 0 instead)
            
            pupilSectionData = (1:size(pupildata,1))';
            pupilSectionData = 0.*(pupilSectionData); 
            
            for columnIndex = 1:size(horizontaldata,2)
                temporaryHorizontalSectionData = (horizontaldata(1:end,columnIndex));
                horizontalSectionData = horizontalSectionData + temporaryHorizontalSectionData; % allows for average to be taken
                % horizontalSectionArrayStart = 1 + size(horizontalSectionData,2); 
                %horizontalSectionArrayEnd = columnIndex*size(temporaryHorizontalSectionData,1); 
                %horizontalSectionData(horizontalSectionArrayStart:horizontalSectionArrayEnd) = temporaryHorizontalSectionData;
            end
            horizontalSectionData = horizontalSectionData./size(horizontaldata,2); % average horizontal data

            for columnIndex = 1:size(pupildata,2)
                temporaryPupilSectionData = (pupildata(1:end,columnIndex));
                pupilSectionData = pupilSectionData + temporaryPupilSectionData;
            end
            pupilSectionData = pupilSectionData./size(pupildata,2); % average pupil data

            %app.FsValue (default) = 500; 
            %frequency = app.FsValueSpinner.Value/(size(horizontalSectionData,1)).*(0:((size(horizontalSectionData,1)))/2); 
            plot(app.HorizontalSectionPlot, frequency, app.createFFTData(horizontalSectionData))
            plot(app.PupilSectionPlot, frequency, app.createFFTData(pupilSectionData))
            
        end

        function updateMovementPlot(app) %added 6/3/2024
           % Pull and Plot Movement Data
           [horizontaldata, pupildata] = app.extractMovementData;
           %frequency calculation
           frequency = app.FsValueSpinner.Value/app.maxSamples.*(0:(app.maxSamples/2)); % see function updateSectionPlot for explanation of calculations

           singleHorizontalMovement = app.createFFTData(horizontaldata(1:end,app.currentMovementIndex)); 
           plot(app.HorizontalMovementPlot,frequency,singleHorizontalMovement)

           singlePupilMovement = app.createFFTData(pupildata(1:end,app.currentMovementIndex)); 
           plot(app.PupilMovementPlot,frequency,singlePupilMovement)
        end

        function fileName = returnFileName(app)
             % Evaluate Single File or Batch
            if(ischar(app.userSelectedFile))
                fileName = app.userSelectedFile;
            else
                fileName = app.userSelectedFile{app.currentFileIndex};
            end
        end

        % Update Labels for New Section
        function resetSectionMovementIndex(app)
            % Load Movement Index
            app.currentMovementIndex = 1;
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
            app.totalCurrentMovements = app.mapSectionNameSectionCount(app.SectionNameDropDown.Value);
            app.totalMovementCount.Text = string(app.totalCurrentMovements);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.eyeSelectionMap = containers.Map([1, 2, 3], ["Left Eye", "Right Eye", "Binocular"]); %Changed Combined into Binocular 6/12/2024
            app.mapDataChannelClassification = containers.Map(["Horizontal","Vertical","Pupil"],["HClass","VClass","PClass"]);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Identify Table Pathing to Load
            app.getTablePathing;
            % Reset Labels for Section
            app.resetSectionMovementIndex;
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            % Set Boolean for Button Condition
            app.nextDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex;
            app.loadTableData;
            % Reset Button Condition
            app.nextDataFileButtonPressed = false;
        end

        % Button pushed function: nextMovementButton
        function nextMovementButtonPushed(app, event)
            app.nextMovementButtonPressed = true;
            app.getNextMovement;
            app.nextMovementButtonPressed = false;
            app.updateMovementPlot; % 6/3/2024
        end

        % Button pushed function: previousMovementButton
        function previousMovementButtonPushed(app, event)
            app.previousMovementButtonPressed = true;
            app.getNextMovement;
            app.previousMovementButtonPressed = false;
            app.updateMovementPlot; % 6/3/2024
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            app.previousDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex
            app.loadTableData;
            app.previousDataFileButtonPressed = false;
        end

        % Value changed function: SectionNameDropDown
        function SectionNameDropDownValueChanged(app, event)
            app.currentSectionIndex = find(strcmp(app.SectionNameDropDown.Value,keys(app.mapSectionNameSectionCount)));
            app.resetSectionMovementIndex;
            app.loadSectionOfData;
            app.loadMetricTableData;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.dataLoadedBoolean)
                % Retrieve FileName
                fileName = app.returnFileName;
    
                % Save Metric Table Data to ANALYSIS_METRIC Directory
                saveFilePathway = app.createMetricTableDirectory(app.userSelectedFilePath);
                % Check If Directory Does Not Exist
                if(~exist(saveFilePathway,'dir'))
                    mkdir(saveFilePathway);
                end
                saveFunction.saveData(app.MetricTable,fullfile(saveFilePathway,fileName),"-mat");
    
            % Force Data Load
            else
                app.LoadDataButtonPushed;
            end            
        end

        % Selection changed function: EyeSelectorButtonGroup
        function EyeSelectorButtonGroupSelectionChanged(app, event)
           switch app.EyeSelectorButtonGroup.SelectedObject.Text
                case "Left Eye"
                    app.currentEyeSelectionIndex = 1;
               case "Right Eye"
                    app.currentEyeSelectionIndex = 2;
               case "Binocular"
                    app.currentEyeSelectionIndex = 3;
           end
           app.updateSectionPlot;
           app.updateMovementPlot;%6/3/2024
        end

        % Value changed function: FsValueSpinner
        function FsValueSpinnerValueChanged(app, event)
            app.updateSectionPlot;
            app.updateMovementPlot %6/3/2024
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HorizontalSectionPlot = uiaxes(app.UIFigure);
            title(app.HorizontalSectionPlot, 'Horizontal Average Section FFT')
            xlabel(app.HorizontalSectionPlot, 'Frequency (Hz)')
            ylabel(app.HorizontalSectionPlot, '|Amplitude|')
            zlabel(app.HorizontalSectionPlot, 'Z')
            app.HorizontalSectionPlot.XLim = [0 100];
            app.HorizontalMovementPlot = uiaxes(app.UIFigure);
            title(app.HorizontalMovementPlot, 'Horizontal Movement FFT')
            xlabel(app.HorizontalMovementPlot, 'Frequency (Hz)')
            ylabel(app.HorizontalMovementPlot, '|Amplitude|')
            zlabel(app.HorizontalMovementPlot, 'Z')
            app.HorizontalMovementPlot.XLim = [0 100];
            app.PupilSectionPlot = uiaxes(app.UIFigure);
            title(app.PupilSectionPlot, 'Pupil  Average Section FFT')
            xlabel(app.PupilSectionPlot, 'Frequency(Hz)')
            ylabel(app.PupilSectionPlot, '|Amplitude|')
            zlabel(app.PupilSectionPlot, 'Z')
            app.PupilSectionPlot.XLim = [0 100];
            app.PupilMovementPlot = uiaxes(app.UIFigure);
            title(app.PupilMovementPlot, 'Pupil Movement FFT')
            xlabel(app.PupilMovementPlot, 'Frequency (Hz)')
            ylabel(app.PupilMovementPlot, '|Amplitude|')
            zlabel(app.PupilMovementPlot, 'Z')
            app.PupilMovementPlot.XLim = [0 100];
            app.CurrentFileSelectionLabel = uilabel(app.UIFigure);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.UIFigure);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.UIFigure);
            app.totalFileCountLabel.Text = '###';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.previousMovementButton = uibutton(app.UIFigure, 'push');
            app.previousMovementButton.ButtonPushedFcn = createCallbackFcn(app, @previousMovementButtonPushed, true);
            app.previousMovementButton.Text = 'PREVIOUS';
            app.nextMovementButton = uibutton(app.UIFigure, 'push');
            app.nextMovementButton.ButtonPushedFcn = createCallbackFcn(app, @nextMovementButtonPushed, true);
            app.nextMovementButton.Text = 'NEXT';
            app.MetricTable = uitable(app.UIFigure);
            app.MetricTable.ColumnName = {'RSI'; 'REI'; 'RA'; 'PVI'; 'PV'; 'FA'; 'RSID'; 'REID'; 'RAD'; 'PDROCI'; 'PDROC'; 'ID'; 'FD'; 'HClass'; 'VClass'; 'PClass'};
            app.MetricTable.RowName = {'Left Eye'; 'Right Eye'; 'Binocular'};
            app.MetricTable.SelectionType = 'row';
            app.EyeSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.EyeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSelectorButtonGroupSelectionChanged, true);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.LeftEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.LeftEyeButton.Text = 'Left Eye';
            app.BinocularButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.BinocularButton.Text = 'Binocular';
            app.BinocularButton.Value = true;
            app.RightEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.RightEyeButton.Text = 'Right Eye';
            app.MetricSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.MetricSelectorButtonGroup.Title = 'Metric Selector';
            app.SelectLatencyButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectLatencyButton.Text = 'Select Latency';
            app.SelectPeakVelocityButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectPeakVelocityButton.Text = 'Select Peak Velocity';
            app.SelectResponseAmplitudeButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectResponseAmplitudeButton.Text = 'Select Response Amplitude';
            app.ClassificationSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassificationSelectorButtonGroup.Title = 'Classification Selector';
            app.ClassifyButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.ClassifyButton.Text = 'Classify';
            app.ClassificationLabelsDropDownLabel = uilabel(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDownLabel.Text = 'Classification Labels';
            app.ClassificationLabelsDropDown = uidropdown(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDown.Items = {'Good', 'Bad', 'Asymmetrical', 'Transient Saccade', 'Other'};
            app.ClassificationLabelsDropDown.Editable = 'on';
            app.ClassificationLabelsDropDown.Value = 'Good';
            app.DataSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.DataSelectorButtonGroup.Title = 'Data Selector';
            app.ClassificationLabelsDropDown_2Label = uilabel(app.DataSelectorButtonGroup);
            app.ClassificationLabelsDropDown_2Label.Text = 'Data Channel Labels';
            app.DataChannelLabelsDropDown = uidropdown(app.DataSelectorButtonGroup);
            app.DataChannelLabelsDropDown.Items = {'Horizontal', 'Vertical', 'Pupil'};
            app.DataChannelLabelsDropDown.Editable = 'on';
            app.DataChannelLabelsDropDown.Value = 'Horizontal';
            app.NextFileButton = uibutton(app.UIFigure, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.UIFigure, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SectionNameDropDownLabel = uilabel(app.UIFigure);
            app.SectionNameDropDownLabel.Text = 'Section  Name';
            app.SectionNameDropDown = uidropdown(app.UIFigure);
            app.SectionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionNameDropDownValueChanged, true);
            app.movementCounterOfLabel = uilabel(app.UIFigure);
            app.movementCounterOfLabel.Text = 'of';
            app.currentMovementCountIndex = uilabel(app.UIFigure);
            app.currentMovementCountIndex.Text = '###';
            app.totalMovementCount = uilabel(app.UIFigure);
            app.totalMovementCount.Text = '###';
            app.MovementLabel = uilabel(app.UIFigure);
            app.MovementLabel.Text = 'Movement';
            app.FsValueSpinnerLabel = uilabel(app.UIFigure);
            app.FsValueSpinnerLabel.Text = 'Fs Value';
            app.FsValueSpinner = uispinner(app.UIFigure);
            app.FsValueSpinner.ValueChangedFcn = createCallbackFcn(app, @FsValueSpinnerValueChanged, true);
            app.FsValueSpinner.Value = 500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fftAnalysisModule

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end