classdef stepAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        analysisContainer               matlab.ui.Figure
        ExportEnsemblesButton           matlab.ui.control.Button
        SamplingRateSpinner             matlab.ui.control.Spinner
        SamplingRateSpinnerLabel        matlab.ui.control.Label
        MovementLabel                   matlab.ui.control.Label
        totalMovementCount              matlab.ui.control.Label
        currentMovementCountIndex       matlab.ui.control.Label
        movementCounterOfLabel          matlab.ui.control.Label
        SectionNameDropDown             matlab.ui.control.DropDown
        SectionNameDropDownLabel        matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PreviousFileButton              matlab.ui.control.Button
        NextFileButton                  matlab.ui.control.Button
        DataSelectorButtonGroup         matlab.ui.container.ButtonGroup
        PlotVersionButton               matlab.ui.control.StateButton
        DataChannelLabelsDropDown       matlab.ui.control.DropDown
        ClassificationLabelsDropDown_2Label  matlab.ui.control.Label
        ClassificationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationCommentsDropDown  matlab.ui.control.DropDown
        ClassificationCommentsDropDownLabel  matlab.ui.control.Label
        BadButton                       matlab.ui.control.Button
        GoodButton                      matlab.ui.control.Button
        MetricSelectorButtonGroup       matlab.ui.container.ButtonGroup
        FAButton                        matlab.ui.control.Button
        IDButton                        matlab.ui.control.Button
        SelectREIButton                 matlab.ui.control.Button
        SelectPeakVelocityButton        matlab.ui.control.Button
        SelectRSIButton                 matlab.ui.control.Button
        EyeSelectorButtonGroup          matlab.ui.container.ButtonGroup
        RightEyeButton                  matlab.ui.control.ToggleButton
        CombinedButton                  matlab.ui.control.ToggleButton
        LeftEyeButton                   matlab.ui.control.ToggleButton
        MetricTable                     matlab.ui.control.Table
        nextMovementButton              matlab.ui.control.Button
        previousMovementButton          matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        totalFileCountLabel             matlab.ui.control.Label
        currentFileIndexLabel           matlab.ui.control.Label
        ofLabel                         matlab.ui.control.Label
        CurrentFileSelectionLabel       matlab.ui.control.Label
        IndividualVelocityPlot          matlab.ui.control.UIAxes
        VelocityEnsemblePlot            matlab.ui.control.UIAxes
        IndividualMovementPlot          matlab.ui.control.UIAxes
        MovementEnsemblePlot            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        classificationValue % Value for Classification (1 - Good, 0 - Bad)
        currentEyeSelectionIndex = 3; % Default Eye Selection Index (Combined)
        currentFileIndex = 1; % Default Current File Index
        currentFileSectionNames % Table Variable Names Properties
        currentLoadedData % Current Sub-Table Loaded from Main Table
        currentMetrics % Child of TableOfMetrics singular table entry
        currentMetricTableOffset = 0; % Offset Analysis Result Placement in Metric Table
        currentTableOfMetrics % Loaded Table for all Metric Analysis of File
        currentMovementIndex = 1; % Default Current Movement Index
        currentSectionIndex% Current Position within Table Data
        currentTableData % Presently Loaded Table Data
        dataLoadedBoolean = false; % Default Load Boolean
        mapSectionNameSectionCount; % Map/Value of Section Name to Counts
        mapDataChannelClassification; % Map/Value of Channel to Classification
        mapMetricIndex; % Map of Current Metrics for Horizontal
        nextDataFileButtonPressed = false; % Default Selection Boolean 
        nextMovementButtonPressed = false; % Default Selection Boolean
        previousDataFileButtonPressed = false; % Default Selection Boolean
        previousMovementButtonPressed = false; % Default Selection Boolean+
        pviXLineMovementTrace % Vertical Line Variable for Metric Visualization (PVI)
        pviXLineVelocityTrace % Vertical Line Variable for Metric Visualization (PVI)
        reiXLineMovementTrace % Vertical Line Variable for Metric Visualization (REI)
        reiXLineVelocityTrace % Vertical Line Variable for Metric Visualization (REI)
        rsiXLineMovementTrace % Vertical Line Variable for Metric Visualization (RSI)
        rsiXLineVelocityTrace % Vertical Line Variable for Metric Visualization (RSI)
        movementVersionTrace % Calculated Variable for Version Comparison
        velocityVersionTrace % Calculated Variable for Version Comparison
        totalSelectedFiles % Default Total Selected File Objects
        totalCurrentMovements % Maximum Movements for Section Observed
        userSelectedFilePath % User Selected Filepath for Analysis
        userSelectedFile % User Selected Filename(s) for Analysis Cell Array
        
        eyeSelectionMap % Map between button groups and row index
     
    end

    methods (Access = private)

        % Plotting Movement Data (Ensemble, Average, etc.)
        function plotData(app,specificFigureHandle, plotData, isAverageTrace, currentEyeIndex)
            % Value Override if Not Called
            if(isempty(currentEyeIndex))
                currentEyeIndex = app.currentEyeSelectionIndex;
            end

            % Set Color and Transparency (Alpha)
            alphaValue = .5;
            lineWidthValue = .5;

            % Set Xlim Based on Sampling Rate
            xlim(specificFigureHandle,[0,round(size(plotData,1)/app.SamplingRateSpinner.Value)])

            % Time Vector
            timeVector = [linspace(0,round(size(plotData,1)/app.SamplingRateSpinner.Value),size(plotData,1))];

            % Ensure Tick Axes are Auto
            xticks(specificFigureHandle,'auto');
            yticks(specificFigureHandle,'auto');

            % Determine Eye Color
            switch currentEyeIndex
                % Left - Green
                case 1
                    colorVal = [0, 1, 0, alphaValue];
                    tagMessage = "EyeData";
                % Right - Red
                case 2
                    colorVal =  [1, 0, 0, alphaValue];
                    tagMessage = "EyeData";
                % Combined - Blue
                case 3
                    colorVal = [0, 0, 1, alphaValue];
                    tagMessage = "EyeData";
            end

            % Override Color if AverageTrace
            if(isAverageTrace)
                colorVal = [0 0 0 1];
                lineWidthValue = 1;
                tagMessage = "Average";
            end

            % Plot Data
            plot((specificFigureHandle),timeVector,plotData,'Color', colorVal,'LineWidth',lineWidthValue,'Tag',tagMessage);
        end

        % Plot Version Data on Individual Plots
        function plotVersion(app, buttonState)
            if(buttonState)
                currentVersionData = app.createVersionData;
                % Time Vector
                timeVector = [linspace(0,round(size(currentVersionData,1)/app.SamplingRateSpinner.Value),size(currentVersionData,1))];
                hold(app.IndividualMovementPlot,"on")
                app.movementVersionTrace = plot(app.IndividualMovementPlot,timeVector,currentVersionData,'Color',[0 0 0]);
                hold(app.IndividualMovementPlot,"off")
                
    
                hold(app.IndividualVelocityPlot,"on")
                app.velocityVersionTrace = plot(app.IndividualVelocityPlot,timeVector,app.calculateVelocity(currentVersionData),'Color',[0 0 0]);
                hold(app.IndividualVelocityPlot,"off")
            else
                delete(app.movementVersionTrace)
                delete(app.velocityVersionTrace)
            end
        end

        % Calculate Velocity (First-Order Diff)
        function calculatedVelocity = calculateVelocity(app, movementData)
            calculatedVelocity = [zeros(1,size(movementData,2));movmedian(diff(movementData),15,1,"Endpoints","shrink")] .* app.SamplingRateSpinner.Value;
        end

        % Replot Ensemble (Upper) Plots
        function replotEnsembles(app)
            % Collect Ensemble Movement Data
            ensembleData = app.extractEnsembleMovementData;

            % Calculate Ensemble of Velocities
            ensembleVelocityData = app.calculateVelocity(ensembleData);

            % Calculate Average Positional Trace
            averageMovement = mean(ensembleData,2,"omitnan");

            % Calculate Average Velocity Trace
            averageVelocity = app.calculateVelocity(averageMovement);

            % Plot Positional First

            % Plot Movement Ensemble
           app.plotData(app.MovementEnsemblePlot,ensembleData, false,[]);

           hold(app.MovementEnsemblePlot,"on")

           % Plot Movement Ensemble Average
           app.plotData(app.MovementEnsemblePlot,averageMovement,true,[]);

           % Plot Y Line for 0 - Axis
           app.plotYLine(app.MovementEnsemblePlot);
           
           hold(app.MovementEnsemblePlot,"off")

           % Plot Velocity Second

            % Plot Movement Ensemble
           app.plotData(app.VelocityEnsemblePlot,ensembleVelocityData, false,[]);

           hold(app.VelocityEnsemblePlot,"on")

           % Plot Movement Ensemble Average
           app.plotData(app.VelocityEnsemblePlot,averageVelocity,true,[]);

          % Plot YLine for O - Axis
           app.plotYLine(app.VelocityEnsemblePlot)
           
           hold(app.VelocityEnsemblePlot,"off")
        end

        % Plot Y Line at 0 for given Handle
        function plotYLine(app, figureHandle)
           % Hold Current Axis on
           hold(figureHandle,"on")
           % Plot YLine for O - Axis
           yline(figureHandle,0,'Color',[0 0 0],'LineStyle','--',"Tag","YLine");
           % Turn hold off
           hold(figureHandle,"off")
        end

        % Create Version Data
        function versionMovementData = createVersionData(app)
            versionMovementData = (app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(1), " ",app.DataChannelLabelsDropDown.Value)) - app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(2), " ",app.DataChannelLabelsDropDown.Value)))/2;
        end

        % Clear Average Traces from Ensembles
        function clearAverageTraces(app)
            % Find "Average" Tag for Line
            ensembleMovementAverageIndex = find(contains(get(app.MovementEnsemblePlot.Children,"Tag"),"Average"));
            ensembleVelocityAverageIndex = find(contains(get(app.VelocityEnsemblePlot.Children,"Tag"),"Average"));

            delete(app.MovementEnsemblePlot.Children(ensembleMovementAverageIndex))
            delete(app.VelocityEnsemblePlot.Children(ensembleVelocityAverageIndex))
        end

        % Replot Individual (Lower) Plots
        function replotIndividuals(app)
            % Get Current Movement Data from Current Table Data
            if(app.currentEyeSelectionIndex ~= 3)
                currentMovementData = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value));

                % Calculate Velocity of Movement Data
                currentVelocityData = app.calculateVelocity(currentMovementData);
    
                % Plot Movement Data
                app.plotData(app.IndividualMovementPlot,currentMovementData,false,[]);
    
                % Plot Velocity Data
                app.plotData(app.IndividualVelocityPlot,currentVelocityData,false,[]);
            else

                % Index Left
                currentMovementData(:,1) = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(1), " ",app.DataChannelLabelsDropDown.Value));
                % Index Right
                currentMovementData(:,2) = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(2), " ",app.DataChannelLabelsDropDown.Value));
                % Index Combined
                currentMovementData(:,3) = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(3), " ",app.DataChannelLabelsDropDown.Value));
                            
                % Calculate Velocity of Movement Data
                currentVelocityData = app.calculateVelocity(currentMovementData);
    
                % Plot Movement Data Combined
                app.plotData(app.IndividualMovementPlot,currentMovementData(:,3),false,3);

                hold(app.IndividualMovementPlot,"on")

                 % Plot Movement Data Left
                app.plotData(app.IndividualMovementPlot,currentMovementData(:,1),false,1);

                hold(app.IndividualMovementPlot,"on")  
                                
                % Plot Movement Data Right
                app.plotData(app.IndividualMovementPlot,currentMovementData(:,2),false,2);

                hold(app.IndividualMovementPlot,"off")
    
                % Plot Velocity Data Combined
                app.plotData(app.IndividualVelocityPlot,currentVelocityData(:,3),false,3);

                hold(app.IndividualVelocityPlot,"on")
    
                % Plot Velocity Data Left
                app.plotData(app.IndividualVelocityPlot,currentVelocityData(:,1),false,1);

                hold(app.IndividualVelocityPlot,"on")
    
                % Plot Velocity Data Right
                app.plotData(app.IndividualVelocityPlot,currentVelocityData(:,2),false,2);

                hold(app.IndividualVelocityPlot,"off")
            end

            % Plot Y Lines
            app.plotYLine(app.IndividualMovementPlot);
            app.plotYLine(app.IndividualVelocityPlot);
            
            % Update Metric Lines on Axes
            app.updateTemporalMarkers;

            % Check Version Traces
            app.PlotVersionButtonValueChanged;

        end

        % Replots Temporal Metrics onto Individual Plots
        function updateTemporalMarkers(app)
        
            % Retrieve RSI / RSID
            rsiValue = app.getMetricValue(app.getMetricPlacement("RSI"));

            % Retrieve REI / REID
            reiVal = app.getMetricValue(app.getMetricPlacement("REI"));

            % Retrieve PVI / PDROCI
            pviVal = app.getMetricValue(app.getMetricPlacement("PVI"));

            % Clear Old Markers
            app.clearMarkers;

            % Plot if Not NaN
            if(~isnan(rsiValue))
                % Delete Old
                app.rsiXLineMovementTrace = xline(app.IndividualMovementPlot,rsiValue,"LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","RSI");
                app.rsiXLineVelocityTrace = xline(app.IndividualVelocityPlot,rsiValue,"LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","RSI");
            end
            if(~isnan(reiVal))
                app.reiXLineMovementTrace = xline(app.IndividualMovementPlot,reiVal, "LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","REI");
                app.reiXLineVelocityTrace = xline(app.IndividualVelocityPlot,reiVal, "LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","REI");
            end
            if(~isnan(pviVal))
                app.pviXLineVelocityTrace = xline(app.IndividualVelocityPlot,pviVal, "LabelHorizontalAlignment","left","LabelVerticalAlignment","top", "Label","PVI");
                app.pviXLineMovementTrace = xline(app.IndividualMovementPlot,pviVal, "LabelHorizontalAlignment","right","LabelVerticalAlignment","middle", "Label","PVI");
            end

        end

        % Clear Markers from Plots
        function clearMarkers(app)
            % Retrieve RSI / RSID
            rsiValue = app.getMetricValue(app.getMetricPlacement("RSI"));

            % Retrieve REI / REID
            reiVal = app.getMetricValue(app.getMetricPlacement("REI"));

            % Retrieve PVI / PDROCI
            pviVal = app.getMetricValue(app.getMetricPlacement("PVI"));

            % Plot if Not NaN
            if(~isnan(rsiValue))
                % Delte Old
                delete(app.rsiXLineMovementTrace)
                delete(app.rsiXLineVelocityTrace)
            end
            if(~isnan(reiVal))
                delete(app.reiXLineMovementTrace)
                delete(app.reiXLineVelocityTrace)
            end
            if(~isnan(pviVal))
                delete(app.pviXLineVelocityTrace)
                delete(app.pviXLineMovementTrace)
            end
        end

        % Extract movement data from table data
        function extractedMovementData = extractEnsembleMovementData(app)
            
            % Set default values
            extractedMovementData = [];

            % Extract Non-empty cells (cells with movement data)
            selectedMovementData = {app.currentTableData.(app.SectionNameDropDown.Value){~cellfun(@isempty,app.currentTableData.(app.SectionNameDropDown.Value))}}';
            
            % Pull out ALL Data Streams with Good/NaN Classification
            extractedMovementData = app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value));
        end

        function extractedTableData = tableDataExtractor(app, cellTableArray, extractionHeader)
            % Determine Largest Sample Length for Plotting
            maxSamplesList = cellfun(@max,cellfun(@size,cellTableArray,'UniformOutput',false));
            maxSamples = max(maxSamplesList);

            % Preallocate Space 
            % Number of Rows based on First Cell Array Value
            % Number of Cols based on Size of Cell Array
            extractedTableData = nan(maxSamples,size(cellTableArray,1));
            for cellIndex = 1:size(cellTableArray,1)
                % Determine if Classification of Specified Data Channel is Good
                if((app.currentTableOfMetrics{cellIndex, app.SectionNameDropDown.Value}{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(app.currentEyeSelectionIndex)) ~= 0)
                    extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = cellTableArray{cellIndex}.(extractionHeader);
                end
            end
        end

        % Create table of NaN tables
        function metricTable = createMetricTable(app)
            % Returns # of tables in each variable of tableData
            sectionInstances = values(app.mapSectionNameSectionCount);
            
            % Creating empty table with same dimensions as tableData
            metricTable = array2table(cell(max([sectionInstances{:}]), size(sectionInstances, 2)), "VariableNames",keys(app.mapSectionNameSectionCount));

            % Iterates across sectionInstances (Columns)
            for sectionIndex = 1:size(sectionInstances, 2)

                % Iterates down number of movements within tableData
                for movementIndex = 1:sectionInstances{sectionIndex}
                    colSize = size(app.MetricTable.ColumnName,1);

                    % Assign metricTable empty NaN for processing
                    metricTable{movementIndex, sectionIndex} = {horzcat(array2table(NaN(3,colSize-1),'VariableNames',app.MetricTable.ColumnName(1:end-1),'RowNames',app.MetricTable.RowName),array2table(string(NaN(3,1)),'VariableNames',app.MetricTable.ColumnName(end),'RowNames',app.MetricTable.RowName))};
                end
            end
        end

        % Creates directory for metric table saving
        function metricTablePath = createMetricTableDirectory(~, filepath)
          % Replace Key Processing Words with "ANALYSIS_METRICS"
            metricTablePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_METRICS");

            % Check If Directory Does Not Exist
            if(~exist(metricTablePath,'dir'))
                mkdir(metricTablePath);
            end
        end

        % Creates directory for metric table saving
        function figurePath = createAnalysisFigureDirectory(app, filepath, figureType)
          % Replace Key Processing Words with "ANALYSIS_METRICS"
            basePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_FIGURES");
            
            % Add Specified Directory
            figurePath = fullfile(basePath,figureType,app.SectionNameDropDown.Value);

            % Check If Directory Does Not Exist
            if(~exist(figurePath,'dir'))
                mkdir(figurePath);
            end
        end

        % Create Map Container to Map Keys (Section Names) to Values
        % (Movement Counts per Section Name)
        function createSectionNameMap(app)
            % Iterate over each cell in Variable Name
            sectionLogicalCells = cellfun(@(tableVariableName) cellfun(@(sectionData) ~isempty(sectionData),app.currentTableData.(tableVariableName),'UniformOutput',false),app.currentFileSectionNames,'UniformOutput',false);
            sectionMovementCounts = cellfun(@(logicalCell) sum(cell2mat(logicalCell),1), sectionLogicalCells);
            % Map Keys - Section Names to Values - Section Movement Counts 
            app.mapSectionNameSectionCount = containers.Map(app.currentFileSectionNames,sectionMovementCounts);
        end

        % Create NaN table of tables for metric analysis
        function findMetricTable(app,filePath)
            % Retrieve FileName
            fileName = app.returnFileName;
    
            % Check if table exists
            if(isfile(fullfile(app.createMetricTableDirectory(filePath),fileName)))
                loadedMetricTableObject = load(fullfile(app.createMetricTableDirectory(filePath),fileName));
                app.currentTableOfMetrics = loadedMetricTableObject.importedData;
                app.loadMetricTableData;

                % Load in Metrics to Graph
                app.updateTemporalMarkers;

            % Else create NaN metric table
            else
                app.currentTableOfMetrics = app.createMetricTable;
            end
        end
           
        % Load Next Movement Data within Current Table Data
        function getNextMovement(app)

            % Save Current Metric Table State
            app.saveCurrentAnalysis;

            % Evaluate Button Presses
            % Expected Next Button Pressed
            if(app.nextMovementButtonPressed)
                % Check Boundaries and Roll Over to 1 if reached
                if(app.currentMovementIndex + 1 <= app.totalCurrentMovements)
                    app.currentMovementIndex = app.currentMovementIndex + 1;
                else
                    app.currentMovementIndex = 1;
                end    

            % Else Previous Button Pressed
            else
                % Check Boundaries and Roll Over to Last File if reached
                if(app.currentMovementIndex - 1 >= 1)
                    app.currentMovementIndex = app.currentMovementIndex - 1;
                else
                    app.currentMovementIndex = app.totalCurrentMovements;
                end
            end

            % Load Subsection of Table Data
            app.loadSectionOfData;

            % Update Metric Table for New Movement Index
            app.loadMetricTableData;

            % Update Labels
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
        end

        function getSectionNames(app)
            % Load Current Table Variable Names
            app.currentFileSectionNames = app.currentTableData.Properties.VariableNames';     
            % Update Dropdown Items
            app.SectionNameDropDown.Items = app.currentFileSectionNames;
            % Set Default Value to First Item
            app.SectionNameDropDown.Value = app.currentFileSectionNames{1};
            % Map Section Names and Movement Counts
            app.createSectionNameMap;
        end

        % Table Data FILE Pathing Retrieval
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataFileButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                % Check Previous Button Press
                elseif(app.previousDataFileButtonPressed)
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end
                % Section Name Change 
                else
                    app.currentFileIndex = 1;
                end
                
                % Update Labels
                app.currentFileIndexLabel.Text = string(app.currentFileIndex);

            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData();

                % Proceed If Load Successful
                if(~isempty(app.userSelectedFile))
                    % Populate Default Count Variable
                    app.currentFileIndex = 1;
                    app.currentMovementIndex = 1;
                    app.currentSectionIndex = 1;

                    % Evaluate for Single or Multi-File Selection
                    if(ischar(app.userSelectedFile))
                        app.totalSelectedFiles = 1;
                    else
                        app.totalSelectedFiles = size(app.userSelectedFile,1);
                    end

                    % Set Labels
                    app.currentFileIndexLabel.Text = string(app.currentFileIndex);
                    app.totalFileCountLabel.Text = string(app.totalSelectedFiles); 

                    % Load Table Data
                    app.loadTableData;

                    % Update Load Data Boolean
                    app.dataLoadedBoolean = true;
                end
            end
        end
        
        function loadTableData(app)
            % Load Current Table Path and Filename
            if(ischar(app.userSelectedFile))
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile;

            else
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile{app.currentFileIndex});
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile{app.currentFileIndex};
            end
            % Load Map of Section Names and Expected Counts
            app.getSectionNames;
            % Get Metric Table (New if Does Not Exist or Returns File if
            % Exists)
            app.findMetricTable(app.userSelectedFilePath);
            % Load in Metric Table File
            app.loadMetricTableData;
            % Load in Section Data of File (Default)
            app.loadSectionOfData;

        end

        % Load Metric Table Based on Current Movement Index (Row) and
        % Section Index (Column)
        function loadMetricTableData(app)
           app.currentMetrics = app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex};  
           app.MetricTable.Data = app.currentMetrics{:};
        end

        function loadSectionOfData(app)
            app.currentLoadedData = app.currentTableData{app.currentMovementIndex,app.currentSectionIndex};
        end

        function fileName = returnFileName(app)
             % Evaluate Single File or Batch
            if(ischar(app.userSelectedFile))
                fileName = app.userSelectedFile;
            else
                fileName = app.userSelectedFile{app.currentFileIndex};
            end
        end

        % Update Labels for New Section
        function resetSectionMovementIndex(app)
            % Load Movement Index
            app.currentMovementIndex = 1;
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
            app.totalCurrentMovements = app.mapSectionNameSectionCount(app.SectionNameDropDown.Value);
            app.totalMovementCount.Text = string(app.totalCurrentMovements);
        end

        % Evaluate if Load Data Boolean has been set to true (Data Loaded)
        function booleanFlag = DataLoadedValidation(app)
            if (app.dataLoadedBoolean)
                booleanFlag = app.dataLoadedBoolean;
            % Force Data Load
            else
                app.LoadDataButtonPushed;
                booleanFlag = app.dataLoadedBoolean;
            end
        end

        % Updates Modifications to Current Metric Table
        function updateMetricTable(app)
            app.MetricTable.Data = app.currentMetrics{:};
        end

        % Returns Placement of Current Metric into Table
        function [metricPositionMatrix] = getMetricPlacement(app,metricName)
            metricPositionMatrix(1) = app.currentEyeSelectionIndex;
            metricPositionMatrix(2) = app.mapMetricIndex(metricName) + app.currentMetricTableOffset;
        end

        % Return Metric Value from Current Metric Table
        function metricValue = getMetricValue(app,metricPositionMatrix)
            metricValue = app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)).(1);
        end

        % Evaluate Movement Position Response Amplitude from REI - RSI
        function updateResponseAmplitude(app)
            % Get Current Positions of Response Metrics
            rsiMetricPositionMatrix = app.getMetricPlacement("RSI");
            reiMetricPositionMatrix = app.getMetricPlacement("REI");

            % Retrieve Values
            rsiVal = app.getMetricValue(rsiMetricPositionMatrix);
            reiVal = app.getMetricValue(reiMetricPositionMatrix);

            % Check Values Exist
            rsiBool = isnan(app.currentMetrics{1}(rsiMetricPositionMatrix(1),rsiMetricPositionMatrix(2)).(1));
            reiBool = isnan(app.currentMetrics{1}(reiMetricPositionMatrix(1),reiMetricPositionMatrix(2)).(1));

            % If Both Exist Calculate RA
            if ~rsiBool && ~reiBool
                currentPositionData = app.getYData(app.IndividualMovementPlot);
                currentRA = currentPositionData(app.time2samples(reiVal)) - currentPositionData(app.time2samples(rsiVal));
                metricPositionMatrix = app.getMetricPlacement("RA");
                app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)) = {currentRA};
                app.updateMetricTable;
            end
        end

        % Extract YData from Line within Axes
        function lineYData = getYData(~, figureAxes)
            yLineIndex = find(contains(get(figureAxes.Children,"Tag"),"EyeData"));
            lineDataObject = figureAxes.Children(yLineIndex);
            lineYData = lineDataObject.YData;
        end

        % Converts Time Space to Sample Space
        function samplePoint = time2samples(app, timePoint)
            samplePoint = int32(timePoint * app.SamplingRateSpinner.Value);
        end

        % Takes PVI and Calculates PVI
        function updatePeakVelocity(app)
            pviVal = app.getMetricValue([app.getMetricPlacement("PVI")]);
            currentVelocityData = app.getYData(app.IndividualVelocityPlot);
            currentPeakVelocity = currentVelocityData(app.time2samples(pviVal));
            metricPositionMatrix = app.getMetricPlacement("PV");
            app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)) = {currentPeakVelocity};
            app.updateMetricTable;
        end

        % Calculate Metric Function
        function calculateMetric(app, metricField)
            if(app.DataLoadedValidation())
                % Store UIFigure Visibility State
                handleVisbilityState = app.analysisContainer.HandleVisibility;
                % Temp Turn On Visibility
                app.analysisContainer.HandleVisibility = 'callback';
                % Set Figure for ginput
                set(0,'CurrentFigure',app.analysisContainer)
                
                try
                    % User Selection
                    [indexSelection, ~] = ginput(1);
    
                    % Verify Input
                    if(~isempty(indexSelection))
                        % Value Correction
                        if(mod(indexSelection,1/500)~=0)
                            indexSelection = indexSelection - mod(indexSelection,1/500);
                        end

                        % Assign Value to Metric Table
                        app.assignMetricValue(metricField,indexSelection)
                       
                    else
                        uialert(app.analysisContainer,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    end
                catch
                    uialert(app.analysisContainer,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                end
                % Return Visibility State
                app.analysisContainer.HandleVisibility = handleVisbilityState;
               
                % Update Markers for Metrics
                app.updateTemporalMarkers;
            end
        end

        % Assign Given Metric Field Provided Value
        function assignMetricValue(app, metricFieldName, metricValue)
            % Assign Value to Metric Table
            metricPositionMatrix = app.getMetricPlacement(metricFieldName);
            app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)) = {round(metricValue,3)};

            % Update Metric Table
            app.updateMetricTable;
        end

        % Classify Data with Classification Label
        function classifyData(app, classificationLabel, classificationValue)
            % Assign Value to Metric Table
            app.currentMetrics{1}((app.currentEyeSelectionIndex),classificationLabel) = {classificationValue};

            % Update Metric Table
            app.updateMetricTable;
            app.saveCurrentAnalysis

            % Replot Upper Plots
            app.replotEnsembles;
        end

        % Apply Analysis Metrics from currentMetricTable to Parent Table
        function saveCurrentAnalysis(app)
           app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex} = app.currentMetrics;  
        end

        % Turn Version Plotting off
        function turnOffVersionPlot(app)
            % Turn off Version Plotting
            app.PlotVersionButton.Value = 0;
            app.PlotVersionButtonValueChanged;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.eyeSelectionMap = containers.Map([1, 2, 3], ["Left Eye", "Right Eye", "Combined"]);
            app.mapDataChannelClassification = containers.Map(["Horizontal","Vertical","Pupil"],["HClass","VClass","PClass"]);
            app.mapMetricIndex = containers.Map(["RSI","REI","RA","PVI","PV","FA","ID"],[1,2,3,4,5,6,7]);
            app.analysisContainer.WindowStyle = 'alwaysontop';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Set Default Title
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            % Identify Table Pathing to Load
            app.getTablePathing;
            if(app.DataLoadedValidation)
                % Reset Labels for Section
                app.resetSectionMovementIndex;
                % Plot Initial Data
                % Replot Upper Plots
                app.replotEnsembles;
                % Replot Lower Plots
                app.replotIndividuals;
            end
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            % Set Boolean for Button Condition
            app.nextDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex;
            app.loadTableData;
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
            % Reset Button Condition
            app.nextDataFileButtonPressed = false;
        end

        % Button pushed function: nextMovementButton
        function nextMovementButtonPushed(app, event)
            app.nextMovementButtonPressed = true;
            app.getNextMovement;
            % Replot Lower Plots
            app.replotIndividuals;
            app.nextMovementButtonPressed = false;
        end

        % Button pushed function: previousMovementButton
        function previousMovementButtonPushed(app, event)
            app.previousMovementButtonPressed = true;
            app.getNextMovement;
            % Replot Lower Plots
            app.replotIndividuals;
            app.previousMovementButtonPressed = false;
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            app.previousDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex
            app.loadTableData;
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
            app.previousDataFileButtonPressed = false;
        end

        % Value changed function: SectionNameDropDown
        function SectionNameDropDownValueChanged(app, event)
            % Save Current Analysis to Main Table
            app.saveCurrentAnalysis;

            % Update Section Name via Dropdown to Column Index
            app.currentSectionIndex = find(strcmp(app.SectionNameDropDown.Value,keys(app.mapSectionNameSectionCount)));
            
            % Set Movement Index to 1
            app.resetSectionMovementIndex;
            
            % Load New Data into Software
            app.loadSectionOfData;

            % Load Indexed Metric Table
            app.loadMetricTableData;
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.DataLoadedValidation())

                % Turn off Version Plotting
                app.turnOffVersionPlot;

                % Save Current Metric Analysis State
                app.saveCurrentAnalysis;

                % Retrieve FileName
                fileName = app.returnFileName;
    
                % Save Metric Table Data to ANALYSIS_METRIC Directory
                saveFilePathway = app.createMetricTableDirectory(app.userSelectedFilePath);

                % Generate Figure path
                figureSavePathway = app.createAnalysisFigureDirectory(app.userSelectedFilePath,"Individuals");

                % Save Metric Data
                saveFunction.saveData(app.currentTableOfMetrics,fullfile(saveFilePathway,fileName),"-mat");

                % Remove Markers for Saving Figures
                app.clearMarkers;

                % Save Individual Positional Data
                figureFileName = strrep(fileName,".mat","");
                exportgraphics(app.IndividualMovementPlot,fullfile(figureSavePathway,strcat(figureFileName,"_MovementTrace_",app.DataChannelLabelsDropDown.Value,"_",string(app.currentMovementIndex),".emf")));
                saveas(app.IndividualMovementPlot,fullfile(figureSavePathway,strcat(figureFileName,"_MovementTrace_",app.DataChannelLabelsDropDown.Value,"_",string(app.currentMovementIndex),".fig")),'fig');
                
                % Save Individual Positional Data
                exportgraphics(app.IndividualVelocityPlot,fullfile(figureSavePathway,strcat(figureFileName,"_VelocityTrace_",app.DataChannelLabelsDropDown.Value,"_",string(app.currentMovementIndex),".emf")));
                saveas(app.IndividualVelocityPlot,fullfile(figureSavePathway,strcat(figureFileName,"_VelocityTrace_",app.DataChannelLabelsDropDown.Value,"_",string(app.currentMovementIndex),".fig")),'fig');

                % Update Markers
                app.replotIndividuals;

                % Popup Save Notification
                uialert(app.analysisContainer ,"Save Successful","Save","Modal",true,"Icon","success")
            end            
        end

        % Selection changed function: EyeSelectorButtonGroup
        function EyeSelectorButtonGroupSelectionChanged(app, event)
           switch app.EyeSelectorButtonGroup.SelectedObject.Text
                case "Left Eye"
                    app.currentEyeSelectionIndex = 1;
               case "Right Eye"
                    app.currentEyeSelectionIndex = 2;
               case "Combined"
                    app.currentEyeSelectionIndex = 3;
           end
           % Update Data
           app.replotEnsembles;
           % Replot Upper Plots
           app.replotIndividuals;
        end

        % Button pushed function: SelectRSIButton
        function SelectRSIButtonPushed(app, event)
            % Run Metric Calculation
            app.calculateMetric("RSI");
            
            % Check RA Status
            app.updateResponseAmplitude;
        end

        % Value changed function: DataChannelLabelsDropDown
        function DataChannelLabelsDropDownValueChanged(app, event)
            % Evaluate if Pupil/Horizontal Selected
            if(contains(app.DataChannelLabelsDropDown.Value,"Pupil"))
                app.currentMetricTableOffset = 6;
                app.IDButton.Enable = 1;
            else
                app.currentMetricTableOffset = 0;
                app.IDButton.Enable = 0;
            end
            % Trigger Replot
            app.replotEnsembles;
            app.replotIndividuals;
        end

        % Button pushed function: SelectREIButton
        function SelectREIButtonPushed(app, event)
            % Run Metric Calculation
            app.calculateMetric("REI");

            % Check RA Status
            app.updateResponseAmplitude;
        end

        % Button pushed function: SelectPeakVelocityButton
        function SelectPeakVelocityButtonPushed(app, event)
            % Run Metric Calculation
            app.calculateMetric("PVI");
            
            % Update PV with PVI
            app.updatePeakVelocity;
        end

        % Button pushed function: GoodButton
        function GoodButtonPushed(app, event)
            % Determine Classification
            classificationType = app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value);

            app.classifyData(classificationType,1);
        end

        % Button pushed function: BadButton
        function BadButtonPushed(app, event)
            % Determine Classification
            classificationType = app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value);

            app.classifyData(classificationType,0);

        end

        % Value changed function: ClassificationCommentsDropDown
        function ClassificationCommentsDropDownValueChanged(app, event)
            app.classifyData("Comments",app.ClassificationCommentsDropDown.Value);
        end

        % Button pushed function: IDButton
        function IDButtonPushed(app, event)
            tempData = app.getYData(app.IndividualMovementPlot);
            app.assignMetricValue("ID",mean(tempData(1:50)));
        end

        % Button pushed function: FAButton
        function FAButtonPushed(app, event)
            tempData = app.getYData(app.IndividualMovementPlot);
            app.assignMetricValue("FA",mean(tempData(end-49:end)));
        end

        % Key press function: analysisContainer
        function analysisContainerKeyPress(app, event)
            switch event.Key
                case "l"
                    app.LoadDataButtonPushed;
                case "q"
                    app.SelectRSIButtonPushed;
                case "w"
                    app.SelectPeakVelocityButtonPushed;
                case "e"
                    app.SelectREIButtonPushed;
                case "r"
                    if(app.IDButton.Enable)
                        app.IDButtonPushed;
                    end
                case "t"
                    app.FAButtonPushed;
                case "g"
                    app.GoodButtonPushed
                case "b"
                    app.BadButtonPushed;
                case "s"
                    app.SaveButtonPushed;
                case "a"
                    app.previousMovementButtonPushed;
                case "d"
                    app.nextMovementButtonPushed;
                case "z"
                    app.PreviousFileButtonPushed;
                case "c"
                    app.NextFileButtonPushed;
                case "h"
                    app.DataChannelLabelsDropDown.Value = "Horizontal";
                    app.DataChannelLabelsDropDownValueChanged;
                case "j"
                    app.DataChannelLabelsDropDown.Value = "Vertical";
                    app.DataChannelLabelsDropDownValueChanged;
                case "k"
                    app.DataChannelLabelsDropDown.Value = "Pupil";
                    app.DataChannelLabelsDropDownValueChanged;
            end


        end

        % Close request function: analysisContainer
        function analysisContainerCloseRequest(app, event)
            userSelection = uiconfirm(app.analysisContainer,"Close Analysis Module?","Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    delete(app)
            end            
        end

        % Button pushed function: ExportEnsemblesButton
        function ExportEnsemblesButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.DataLoadedValidation())
                % Retrieve FileName
                currentFileName = app.returnFileName;
                figureFileName = strrep(currentFileName,".mat","");

                % Clear Average Traces for Export
                app.turnOffVersionPlot;
                app.clearAverageTraces;

                % Generate Directory Pathing
                figureSavePathway = app.createAnalysisFigureDirectory(app.userSelectedFilePath,"Ensembles");

                % Save Ensemble Positional Data
                exportgraphics(app.MovementEnsemblePlot,fullfile(figureSavePathway,strcat(figureFileName,"_MovementEnsembleTrace_",app.DataChannelLabelsDropDown.Value,".emf")));

                % Save Ensemble Positional Data
                saveas(app.MovementEnsemblePlot,fullfile(figureSavePathway,strcat(figureFileName,"_MovementEnsembleTrace_",app.DataChannelLabelsDropDown.Value)),"fig");
                
                % Save Individual Positional Data
                exportgraphics(app.VelocityEnsemblePlot,fullfile(figureSavePathway,strcat(figureFileName,"_VelocityEnsembleTrace_",app.DataChannelLabelsDropDown.Value,".emf")));
                
                % Save Individual Positional Data
                saveas(app.VelocityEnsemblePlot,fullfile(figureSavePathway,strcat(figureFileName,"_VelocityEnsembleTrace_",app.DataChannelLabelsDropDown.Value)),"fig");

                % Replot Averages with Ensembles
                app.replotEnsembles;

                % Popup Save Notification
                uialert(app.analysisContainer ,"Save Successful","Save","Modal",true,"Icon","success")
            end   
        end

        % Value changed function: PlotVersionButton
        function PlotVersionButtonValueChanged(app, event)
            app.plotVersion(app.PlotVersionButton.Value);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.analysisContainer = uifigure('Visible', 'off');
            app.analysisContainer.Name = 'MATLAB App';
            app.analysisContainer.CloseRequestFcn = createCallbackFcn(app, @analysisContainerCloseRequest, true);
            app.analysisContainer.KeyPressFcn = createCallbackFcn(app, @analysisContainerKeyPress, true);
            app.MovementEnsemblePlot = uiaxes(app.analysisContainer);
            title(app.MovementEnsemblePlot, 'Movement Ensemble Plot')
            xlabel(app.MovementEnsemblePlot, 'Time (Seconds)')
            ylabel(app.MovementEnsemblePlot, 'Final Position')
            zlabel(app.MovementEnsemblePlot, 'Z')
            app.MovementEnsemblePlot.YAxisLocation = 'right';
            app.IndividualMovementPlot = uiaxes(app.analysisContainer);
            title(app.IndividualMovementPlot, 'Individual Movement Plot')
            xlabel(app.IndividualMovementPlot, 'Time (Seconds)')
            ylabel(app.IndividualMovementPlot, 'Final Positoon')
            zlabel(app.IndividualMovementPlot, 'Z')
            app.IndividualMovementPlot.YAxisLocation = 'right';
            app.VelocityEnsemblePlot = uiaxes(app.analysisContainer);
            title(app.VelocityEnsemblePlot, 'Velocity Ensemble Plot')
            xlabel(app.VelocityEnsemblePlot, 'Time (Seconds)')
            ylabel(app.VelocityEnsemblePlot, 'Velocity')
            zlabel(app.VelocityEnsemblePlot, 'Z')
            app.IndividualVelocityPlot = uiaxes(app.analysisContainer);
            title(app.IndividualVelocityPlot, 'Individual Velocity Plot')
            xlabel(app.IndividualVelocityPlot, 'Time (Seconds)')
            ylabel(app.IndividualVelocityPlot, 'Velocity')
            zlabel(app.IndividualVelocityPlot, 'Z')
            app.CurrentFileSelectionLabel = uilabel(app.analysisContainer);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.ofLabel = uilabel(app.analysisContainer);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.analysisContainer);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.analysisContainer);
            app.totalFileCountLabel.Text = '###';
            app.LoadDataButton = uibutton(app.analysisContainer, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.previousMovementButton = uibutton(app.analysisContainer, 'push');
            app.previousMovementButton.ButtonPushedFcn = createCallbackFcn(app, @previousMovementButtonPushed, true);
            app.previousMovementButton.Text = 'PREVIOUS';
            app.nextMovementButton = uibutton(app.analysisContainer, 'push');
            app.nextMovementButton.ButtonPushedFcn = createCallbackFcn(app, @nextMovementButtonPushed, true);
            app.nextMovementButton.Text = 'NEXT';
            app.MetricTable = uitable(app.analysisContainer);
            app.MetricTable.ColumnName = {'RSI'; 'REI'; 'RA'; 'PVI'; 'PV'; 'FA'; 'RSID'; 'REID'; 'RAD'; 'PDROCI'; 'PDROC'; 'FD'; 'ID'; 'HClass'; 'VClass'; 'PClass'; 'Comments'};
            app.MetricTable.RowName = {'Left Eye'; 'Right Eye'; 'Combined'};
            app.MetricTable.SelectionType = 'row';
            app.EyeSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.EyeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSelectorButtonGroupSelectionChanged, true);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.LeftEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.LeftEyeButton.Text = 'Left Eye';
            app.CombinedButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.CombinedButton.Text = 'Combined';
            app.CombinedButton.Value = true;
            app.RightEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.RightEyeButton.Text = 'Right Eye';
            app.MetricSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.MetricSelectorButtonGroup.Title = 'Metric Selector';
            app.SelectRSIButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectRSIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRSIButtonPushed, true);
            app.SelectRSIButton.Text = 'Select RSI';
            app.SelectPeakVelocityButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectPeakVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeakVelocityButtonPushed, true);
            app.SelectPeakVelocityButton.Text = 'Select Peak Velocity';
            app.SelectREIButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectREIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectREIButtonPushed, true);
            app.SelectREIButton.Text = 'Select REI';
            app.IDButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.IDButton.ButtonPushedFcn = createCallbackFcn(app, @IDButtonPushed, true);
            app.IDButton.Enable = 'off';
            app.IDButton.Text = 'ID';
            app.FAButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.FAButton.ButtonPushedFcn = createCallbackFcn(app, @FAButtonPushed, true);
            app.FAButton.Text = 'FA';
            app.ClassificationSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.ClassificationSelectorButtonGroup.Title = 'Classification Selector';
            app.GoodButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.GoodButton.ButtonPushedFcn = createCallbackFcn(app, @GoodButtonPushed, true);
            app.GoodButton.Text = 'Good';
            app.BadButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.BadButton.ButtonPushedFcn = createCallbackFcn(app, @BadButtonPushed, true);
            app.BadButton.Text = 'Bad';
            app.ClassificationCommentsDropDownLabel = uilabel(app.ClassificationSelectorButtonGroup);
            app.ClassificationCommentsDropDownLabel.Text = 'Classification Comments';
            app.ClassificationCommentsDropDown = uidropdown(app.ClassificationSelectorButtonGroup);
            app.ClassificationCommentsDropDown.Items = {'None', 'Asymmetrical', 'Loss of Fusion', 'Overshoot', 'Undershoot', 'Sequential Saccade', 'Saccade In Transient'};
            app.ClassificationCommentsDropDown.Editable = 'on';
            app.ClassificationCommentsDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassificationCommentsDropDownValueChanged, true);
            app.ClassificationCommentsDropDown.Value = 'None';
            app.DataSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.DataSelectorButtonGroup.Title = 'Data Selector';
            app.ClassificationLabelsDropDown_2Label = uilabel(app.DataSelectorButtonGroup);
            app.ClassificationLabelsDropDown_2Label.Text = 'Data Channel Labels';
            app.DataChannelLabelsDropDown = uidropdown(app.DataSelectorButtonGroup);
            app.DataChannelLabelsDropDown.Items = {'Horizontal', 'Vertical', 'Pupil'};
            app.DataChannelLabelsDropDown.ValueChangedFcn = createCallbackFcn(app, @DataChannelLabelsDropDownValueChanged, true);
            app.DataChannelLabelsDropDown.Value = 'Horizontal';
            app.PlotVersionButton = uibutton(app.DataSelectorButtonGroup, 'state');
            app.PlotVersionButton.ValueChangedFcn = createCallbackFcn(app, @PlotVersionButtonValueChanged, true);
            app.PlotVersionButton.Text = 'Plot Version';
            app.NextFileButton = uibutton(app.analysisContainer, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.analysisContainer, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SaveButton = uibutton(app.analysisContainer, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SectionNameDropDownLabel = uilabel(app.analysisContainer);
            app.SectionNameDropDownLabel.Text = 'Section  Name';
            app.SectionNameDropDown = uidropdown(app.analysisContainer);
            app.SectionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionNameDropDownValueChanged, true);
            app.movementCounterOfLabel = uilabel(app.analysisContainer);
            app.movementCounterOfLabel.Text = 'of';
            app.currentMovementCountIndex = uilabel(app.analysisContainer);
            app.currentMovementCountIndex.Text = '###';
            app.totalMovementCount = uilabel(app.analysisContainer);
            app.totalMovementCount.Text = '###';
            app.MovementLabel = uilabel(app.analysisContainer);
            app.MovementLabel.Text = 'Movement';
            app.SamplingRateSpinnerLabel = uilabel(app.analysisContainer);
            app.SamplingRateSpinnerLabel.Text = 'Sampling Rate';
            app.SamplingRateSpinner = uispinner(app.analysisContainer);
            app.SamplingRateSpinner.Limits = [0 10000];
            app.SamplingRateSpinner.RoundFractionalValues = 'on';
            app.SamplingRateSpinner.Value = 500;
            app.ExportEnsemblesButton = uibutton(app.analysisContainer, 'push');
            app.ExportEnsemblesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportEnsemblesButtonPushed, true);
            app.ExportEnsemblesButton.Text = 'Export Ensembles';
            % Show the figure after all components are created
            app.analysisContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stepAnalysisModule

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.analysisContainer)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.analysisContainer)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.analysisContainer)
        end
    end
end